classdef app_wxw < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        GridLayout    matlab.ui.container.GridLayout
        LeftPanel     matlab.ui.container.Panel
        UIAxes        matlab.ui.control.UIAxes
        RightPanel    matlab.ui.container.Panel
        DanceButton   matlab.ui.control.Button
        EditorButton  matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        dancer % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
                % load postures
                num_postures = size(ls('postures'), 1) - 2;
                app.dancer = robot('dancer');
                all_angles = zeros(num_postures, length(app.dancer.sections));
                for i = 1: num_postures
                    load(['postures/' num2str(i) '.mat'], 'angles')
                    all_angles(i, :) = angles;
                end
                
                app.dancer.move_to_angle(all_angles(1, :));
                
                app.dancer.draw(app.UIAxes);
                
                
                time = 1 / 5;
                fps = 60;
                
                for i = 1: 2
                    for posture_index = 1: num_postures
                        next_index = posture_index + 1;
                        if next_index > num_postures
                            next_index = 1;
                        end
                        
                        total_frame = floor(time * fps);
                        wait_time = time / total_frame;
                        
                        delta_angle = (all_angles(next_index, :) - all_angles(posture_index, :)) / total_frame;
                        
                        for frame = 1: total_frame
                            app.dancer.rotation(delta_angle);
                            app.dancer.draw(app.UIAxes);
                            pause(wait_time);
                        end
                    end
                end
        end

        % Callback function
        function editorButtonPushed(app, event)
            posture_editer;
        end

        % Callback function
        function danceButtonPushed(app, event)
            
            dance;
        end

        % Button pushed function: DanceButton
        function DanceButtonPushed(app, event)
            dance;
        end

        % Button pushed function: EditorButton
        function EditorButtonPushed(app, event)
            posture_editer;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {504, 504};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {464, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.LeftPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.RightPanel = uipanel(app.GridLayout);
            app.DanceButton = uibutton(app.RightPanel, 'push');
            app.DanceButton.ButtonPushedFcn = createCallbackFcn(app, @DanceButtonPushed, true);
            app.DanceButton.Text = 'Dance';
            app.EditorButton = uibutton(app.RightPanel, 'push');
            app.EditorButton.ButtonPushedFcn = createCallbackFcn(app, @EditorButtonPushed, true);
            app.EditorButton.Text = 'Editor';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_wxw

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end