classdef dance < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        DanceButton             matlab.ui.control.Button
        StopButton              matlab.ui.control.Button
        Feedback                matlab.ui.control.TextArea
        CenterPanel             matlab.ui.container.Panel
        UIAxes                  matlab.ui.control.UIAxes
        RightPanel              matlab.ui.container.Panel
        SpeedSpinnerLabel       matlab.ui.control.Label
        SpeedSpinner            matlab.ui.control.Spinner
        RepeatTimeSpinnerLabel  matlab.ui.control.Label
        RepeatTimeSpinner       matlab.ui.control.Spinner
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        dancer % dancing robot
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DanceButton
        function DanceButtonPushed(app, event)
            if ~exist('postures','dir') || size(ls('postures'), 1) - 2 == 0
                app.Feedback.Visible = true;
                app.Feedback.Value = "You should generate postures by POSTURE EDITER first.";
            else
                app.Feedback.Visible = false;
                
                % load postures
                num_postures = size(ls('postures'), 1) - 2;
                app.dancer = robot('dancer');
                all_angles = zeros(num_postures, length(app.dancer.sections));
                for i = 1: num_postures
                    load(['postures/' num2str(i) '.mat'], 'angles')
                    all_angles(i, :) = angles;
                end
                
                app.dancer.move_to_angle(all_angles(1, :));
                
                app.dancer.draw(app.UIAxes);
                
                
                time = 1 / app.SpeedSpinner.Value;
                fps = 60;
                
                for i = 1: app.RepeatTimeSpinner.Value
                    for posture_index = 1: num_postures
                        next_index = posture_index + 1;
                        if next_index > num_postures
                            next_index = 1;
                        end
                        
                        total_frame = floor(time * fps);
                        wait_time = time / total_frame;
                        
                        delta_angle = (all_angles(next_index, :) - all_angles(posture_index, :)) / total_frame;
                        
                        for frame = 1: total_frame
                            app.dancer.rotation(delta_angle);
                            app.dancer.draw(app.UIAxes);
                            pause(wait_time);
                        end
                    end
                end
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {595, 595, 595};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {595, 595};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {165, '1x', 279};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DanceButton = uibutton(app.LeftPanel, 'push');
            app.DanceButton.ButtonPushedFcn = createCallbackFcn(app, @DanceButtonPushed, true);
            app.DanceButton.Text = 'Dance';
            app.StopButton = uibutton(app.LeftPanel, 'push');
            app.StopButton.Text = 'Stop';
            app.Feedback = uitextarea(app.LeftPanel);
            app.Feedback.Editable = 'off';
            app.Feedback.Visible = 'off';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Stage')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XLim = [-10 10];
            app.UIAxes.YLim = [0 16];
            app.RightPanel = uipanel(app.GridLayout);
            app.SpeedSpinnerLabel = uilabel(app.RightPanel);
            app.SpeedSpinnerLabel.Text = 'Speed';
            app.SpeedSpinner = uispinner(app.RightPanel);
            app.SpeedSpinner.Limits = [1 10];
            app.SpeedSpinner.Value = 5;
            app.RepeatTimeSpinnerLabel = uilabel(app.RightPanel);
            app.RepeatTimeSpinnerLabel.Text = 'Repeat Time';
            app.RepeatTimeSpinner = uispinner(app.RightPanel);
            app.RepeatTimeSpinner.Limits = [1 Inf];
            app.RepeatTimeSpinner.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dance

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end