classdef posture_editer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        GenerateButton           matlab.ui.control.Button
        LoadButton               matlab.ui.control.Button
        FileNameEditFieldLabel   matlab.ui.control.Label
        FileNameEditField        matlab.ui.control.EditField
        ERRORNosuchfileLabel     matlab.ui.control.Label
        clearButton              matlab.ui.control.Button
        CenterPanel              matlab.ui.container.Panel
        UIAxes                   matlab.ui.control.UIAxes
        SaveButton               matlab.ui.control.Button
        ChangecolorButton        matlab.ui.control.Button
        RightPanel               matlab.ui.container.Panel
        HeadSliderLabel          matlab.ui.control.Label
        HeadSlider               matlab.ui.control.Slider
        HeadEditField            matlab.ui.control.NumericEditField
        ChestEditField           matlab.ui.control.NumericEditField
        ChestSliderLabel         matlab.ui.control.Label
        ChestSlider              matlab.ui.control.Slider
        LeftForearmEditField     matlab.ui.control.NumericEditField
        LeftForearmSliderLabel   matlab.ui.control.Label
        LeftForearmSlider        matlab.ui.control.Slider
        RightForearmEditField    matlab.ui.control.NumericEditField
        RightForearmSliderLabel  matlab.ui.control.Label
        RightForearmSlider       matlab.ui.control.Slider
        LeftThighEditField       matlab.ui.control.NumericEditField
        LeftThighSliderLabel     matlab.ui.control.Label
        LeftThighSlider          matlab.ui.control.Slider
        LeftCalfEditField        matlab.ui.control.NumericEditField
        LeftCalfSliderLabel      matlab.ui.control.Label
        LeftCalfSlider           matlab.ui.control.Slider
        RightThighEditField      matlab.ui.control.NumericEditField
        RightThighSliderLabel    matlab.ui.control.Label
        RightThighSlider         matlab.ui.control.Slider
        RightCalfEditField       matlab.ui.control.NumericEditField
        RightCalfSliderLabel     matlab.ui.control.Label
        RightCalfSlider          matlab.ui.control.Slider
        WaistSliderLabel         matlab.ui.control.Label
        WaistSlider              matlab.ui.control.Slider
        WaistEditField           matlab.ui.control.NumericEditField
        LeftArmSliderLabel       matlab.ui.control.Label
        LeftArmSlider            matlab.ui.control.Slider
        LeftArmEditField         matlab.ui.control.NumericEditField
        RightArmSliderLabel      matlab.ui.control.Label
        RightArmSlider           matlab.ui.control.Slider
        RightArmEditField        matlab.ui.control.NumericEditField
        RandomizeButton          matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        posture % the posture displayes
        posture_exist = false % whether the posture is generated
        section_controllers % Sliders or EditFields to control rotation of sections
        num_change_color = 1 % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.XAxis.Visible = false;
            app.UIAxes.YAxis.Visible = false;
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)

            app.posture = robot('posture');
            app.posture_exist = true;
            app.posture.draw(app.UIAxes);
            
            app.posture.head.link_flag = true;
            app.posture.head.link_slider = app.HeadSlider;
            app.posture.head.link_edit_field = app.HeadEditField;
            app.HeadSlider.Enable = true;
            app.HeadEditField.Enable = true;
            
            app.posture.chest.link_flag = true;
            app.posture.chest.link_slider = app.ChestSlider;
            app.posture.chest.link_edit_field = app.ChestEditField;
            app.ChestSlider.Enable = true;
            app.ChestEditField.Enable = true;
            
            app.posture.waist.link_flag = true;
            app.posture.waist.link_slider = app.WaistSlider;
            app.posture.waist.link_edit_field = app.WaistEditField;            
            app.WaistSlider.Enable = true;
            app.WaistEditField.Enable = true;
            
            app.posture.chest.link_flag = true;
            app.posture.chest.link_slider = app.ChestSlider;
            app.posture.chest.link_edit_field = app.ChestEditField;
            app.ChestSlider.Enable = true;
            app.ChestEditField.Enable = true;

            app.posture.left_arm.link_flag = true;
            app.posture.left_arm.link_slider = app.LeftArmSlider;
            app.posture.left_arm.link_edit_field = app.LeftArmEditField;            
            app.LeftArmSlider.Enable = true;
            app.LeftArmEditField.Enable = true;
            
            app.posture.left_forearm.link_flag = true;
            app.posture.left_forearm.link_slider = app.LeftForearmSlider;
            app.posture.left_forearm.link_edit_field = app.LeftForearmEditField;             
            app.LeftForearmSlider.Enable = true;
            app.LeftForearmEditField.Enable = true;

            app.posture.right_arm.link_flag = true;
            app.posture.right_arm.link_slider = app.RightArmSlider;
            app.posture.right_arm.link_edit_field = app.RightArmEditField;             
            app.RightArmSlider.Enable = true;
            app.RightArmEditField.Enable = true;
            
            app.posture.right_forearm.link_flag = true;
            app.posture.right_forearm.link_slider = app.RightForearmSlider;
            app.posture.right_forearm.link_edit_field = app.RightForearmEditField;            
            app.RightForearmSlider.Enable = true;
            app.RightForearmEditField.Enable = true;
            
            app.posture.left_thigh.link_flag = true;
            app.posture.left_thigh.link_slider = app.LeftThighSlider;
            app.posture.left_thigh.link_edit_field = app.LeftThighEditField;            
            app.LeftThighSlider.Enable = true;
            app.LeftThighEditField.Enable = true;
            
            app.posture.right_thigh.link_flag = true;
            app.posture.right_thigh.link_slider = app.RightThighSlider;
            app.posture.right_thigh.link_edit_field = app.RightThighEditField;                 
            app.RightThighSlider.Enable = true;
            app.RightThighEditField.Enable = true;
            
            app.posture.left_calf.link_flag = true;
            app.posture.left_calf.link_slider = app.LeftCalfSlider;
            app.posture.left_calf.link_edit_field = app.LeftCalfEditField;                 
            app.LeftCalfSlider.Enable = true;
            app.LeftCalfEditField.Enable = true;
            
            app.posture.right_calf.link_flag = true;
            app.posture.right_calf.link_slider = app.RightCalfSlider;
            app.posture.right_calf.link_edit_field = app.RightCalfEditField;                 
            app.RightCalfSlider.Enable = true;
            app.RightCalfEditField.Enable = true;
            
            app.RandomizeButton.Enable = true;
            app.SaveButton.Enable = true;
            app.LoadButton.Enable = true;
            app.FileNameEditField.Enable = true;
            app.ChangecolorButton.Enable = true;
        end

        % Value changing function: HeadSlider
        function HeadSliderValueChanging(app, event)
            value = event.Value;
            app.HeadEditField.Value = value;
            
            delta_alpha = value - app.posture.head.rotation_angle;
            app.posture.head.rotation(delta_alpha);
            app.posture.update_position();
            app.posture.draw(app.UIAxes);
        end

        % Value changed function: HeadEditField
        function HeadEditFieldValueChanged(app, event)
            value = app.HeadEditField.Value;
            
            if value < app.HeadSlider.Limits(1)
                app.HeadSlider.Value = app.HeadSlider.Limits(1);
            elseif value > app.HeadSlider.Limits(2)
                app.HeadSlider.Value = app.HeadSlider.Limits(2);
            else
                app.HeadSlider.Value = value;
            end
            
            delta_alpha = value - app.posture.head.rotation_angle;
            app.posture.head.rotation(delta_alpha);
            app.posture.update_position();
            app.posture.draw(app.UIAxes);            
        end

        % Value changed function: ChestEditField
        function ChestEditFieldValueChanged(app, event)
            value = app.ChestEditField.Value;
            if value < app.ChestSlider.Limits(1)
                app.ChestSlider.Value = app.ChestSlider.Limits(1);
            elseif value > app.ChestSlider.Limits(2)
                app.ChestSlider.Value = app.ChestSlider.Limits(2);
            else
                app.ChestSlider.Value = value;
            end
            
            delta_alpha = value - app.posture.chest.rotation_angle;
            app.posture.chest.rotation(delta_alpha);
            app.posture.update_position();
            app.posture.draw(app.UIAxes); 
        end

        % Value changing function: ChestSlider
        function ChestSliderValueChanging(app, event)
            value = event.Value;
            app.ChestEditField.Value = value;
            
            delta_alpha = value- app.posture.chest.rotation_angle;
            app.posture.chest.rotation(delta_alpha);
            app.posture.update_position();
            app.posture.draw(app.UIAxes);
        end

        % Value changing function: WaistSlider
        function WaistSliderValueChanging(app, event)
            value = event.Value;
            app.WaistEditField.Value = value;
            
            delta_alpha = value- app.posture.waist.rotation_angle;
            app.posture.waist.rotation(delta_alpha);
            app.posture.update_position();
            app.posture.draw(app.UIAxes);
            
        end

        % Value changed function: WaistEditField
        function WaistEditFieldValueChanged(app, event)
            value = app.WaistEditField.Value;
            if value < app.WaistSlider.Limits(1)
                app.WaistSlider.Value = app.WaistSlider.Limits(1);
            elseif value > app.WaistSlider.Limits(2)
                app.WaistSlider.Value = app.WaistSlider.Limits(2);
            else
                app.WaistSlider.Value = value;
            end
            
            delta_alpha = value - app.posture.waist.rotation_angle;
            app.posture.waist.rotation(delta_alpha);
            app.posture.update_position();
            app.posture.draw(app.UIAxes); 
        end

        % Value changing function: LeftArmSlider
        function LeftArmSliderValueChanging(app, event)
            value = event.Value;
            app.LeftArmEditField.Value = value;
            
            delta_alpha = value- app.posture.left_arm.rotation_angle;
            app.posture.left_arm.rotation(delta_alpha);
            app.posture.update_position();
            app.posture.draw(app.UIAxes);
        end

        % Value changing function: LeftForearmSlider
        function LeftForearmSliderValueChanging(app, event)
            value = event.Value;
            app.LeftForearmEditField.Value = value;
            
            delta_alpha = value- app.posture.left_forearm.rotation_angle;
            app.posture.left_forearm.rotation(delta_alpha);
            app.posture.update_position();
            app.posture.draw(app.UIAxes);
        end

        % Value changing function: RightArmSlider
        function RightArmSliderValueChanging(app, event)
            value = event.Value;
            app.RightArmEditField.Value = value;
            
            delta_alpha = value- app.posture.right_arm.rotation_angle;
            app.posture.right_arm.rotation(delta_alpha);
            app.posture.update_position();
            app.posture.draw(app.UIAxes);
        end

        % Value changing function: RightForearmSlider
        function RightForearmSliderValueChanging(app, event)
            value = event.Value;
            app.RightForearmEditField.Value = value;
            
            delta_alpha = value- app.posture.right_forearm.rotation_angle;
            app.posture.right_forearm.rotation(delta_alpha);
            app.posture.update_position();
            app.posture.draw(app.UIAxes);
        end

        % Value changing function: LeftThighSlider
        function LeftThighSliderValueChanging(app, event)
            value = event.Value;
            app.LeftThighEditField.Value = value;
            
            delta_alpha = value- app.posture.left_thigh.rotation_angle;
            app.posture.left_thigh.rotation(delta_alpha);
            app.posture.update_position();
            app.posture.draw(app.UIAxes);
        end

        % Value changing function: LeftCalfSlider
        function LeftCalfSliderValueChanging(app, event)
            value = event.Value;
            app.LeftCalfEditField.Value = value;
            
            delta_alpha = value- app.posture.left_calf.rotation_angle;
            app.posture.left_calf.rotation(delta_alpha);
            app.posture.update_position();
            app.posture.draw(app.UIAxes);
            
        end

        % Value changing function: RightThighSlider
        function RightThighSliderValueChanging(app, event)
            value = event.Value;
            app.RightThighEditField.Value = value;
            
            delta_alpha = value- app.posture.right_thigh.rotation_angle;
            app.posture.right_thigh.rotation(delta_alpha);
            app.posture.update_position();
            app.posture.draw(app.UIAxes);
        end

        % Value changing function: RightCalfSlider
        function RightCalfSliderValueChanging(app, event)
            value = event.Value;
            app.RightCalfEditField.Value = value;
            
            delta_alpha = value- app.posture.right_calf.rotation_angle;
            app.posture.right_calf.rotation(delta_alpha);
            app.posture.update_position();
            app.posture.draw(app.UIAxes);
        end

        % Value changed function: LeftArmEditField
        function LeftArmEditFieldValueChanged(app, event)
            value = app.LeftArmEditField.Value;
            if value < app.LeftArmSlider.Limits(1)
                app.LeftArmSlider.Value = app.LeftArmSlider.Limits(1);
            elseif value > app.LeftArmSlider.Limits(2)
                app.LeftArmSlider.Value = app.LeftArmSlider.Limits(2);
            else
                app.LeftArmSlider.Value = value;
            end
            
            delta_alpha = value - app.posture.left_arm.rotation_angle;
            app.posture.left_arm.rotation(delta_alpha);
            app.posture.update_position();
            app.posture.draw(app.UIAxes); 
        end

        % Value changed function: LeftForearmEditField
        function LeftForearmEditFieldValueChanged(app, event)
            value = app.LeftForearmEditField.Value;
            if value < app.LeftForearmSlider.Limits(1)
                app.LeftForearmSlider.Value = app.LeftForearmSlider.Limits(1);
            elseif value > app.LeftForearmSlider.Limits(2)
                app.LeftForearmSlider.Value = app.LeftForearmSlider.Limits(2);
            else
                app.LeftForearmSlider.Value = value;
            end
            
            delta_alpha = value - app.posture.left_forearm.rotation_angle;
            app.posture.left_forearm.rotation(delta_alpha);
            app.posture.update_position();
            app.posture.draw(app.UIAxes); 
        end

        % Value changed function: RightArmEditField
        function RightArmEditFieldValueChanged(app, event)
            value = app.RightArmEditField.Value;
            if value < app.RightArmSlider.Limits(1)
                app.RightArmSlider.Value = app.RightArmSlider.Limits(1);
            elseif value > app.RightArmSlider.Limits(2)
                app.RightArmSlider.Value = app.RightArmSlider.Limits(2);
            else
                app.RightArmSlider.Value = value;
            end
            
            delta_alpha = value - app.posture.right_arm.rotation_angle;
            app.posture.right_arm.rotation(delta_alpha);
            app.posture.update_position();
            app.posture.draw(app.UIAxes); 
        end

        % Value changed function: RightForearmEditField
        function RightForearmEditFieldValueChanged(app, event)
            value = app.RightForearmEditField.Value;
            if value < app.RightForearmSlider.Limits(1)
                app.RightForearmSlider.Value = app.RightForearmSlider.Limits(1);
            elseif value > app.RightForearmSlider.Limits(2)
                app.RightForearmSlider.Value = app.RightForearmSlider.Limits(2);
            else
                app.RightForearmSlider.Value = value;
            end
            
            delta_alpha = value - app.posture.right_forearm.rotation_angle;
            app.posture.right_forearm.rotation(delta_alpha);
            app.posture.update_position();
            app.posture.draw(app.UIAxes); 
        end

        % Value changed function: LeftThighEditField
        function LeftThighEditFieldValueChanged(app, event)
            value = app.LeftThighEditField.Value;
            if value < app.LeftThighSlider.Limits(1)
                app.LeftThighSlider.Value = app.LeftThighSlider.Limits(1);
            elseif value > app.LeftThighSlider.Limits(2)
                app.LeftThighSlider.Value = app.LeftThighSlider.Limits(2);
            else
                app.LeftThighSlider.Value = value;
            end
            
            delta_alpha = value - app.posture.left_thigh.rotation_angle;
            app.posture.left_thigh.rotation(delta_alpha);
            app.posture.update_position();
            app.posture.draw(app.UIAxes); 
        end

        % Value changed function: LeftCalfEditField
        function LeftCalfEditFieldValueChanged(app, event)
            value = app.LeftCalfEditField.Value;
            if value < app.LeftCalfSlider.Limits(1)
                app.LeftCalfSlider.Value = app.LeftCalfSlider.Limits(1);
            elseif value > app.LeftCalfSlider.Limits(2)
                app.LeftCalfSlider.Value = app.LeftCalfSlider.Limits(2);
            else
                app.LeftCalfSlider.Value = value;
            end
            
            delta_alpha = value - app.posture.left_calf.rotation_angle;
            app.posture.left_calf.rotation(delta_alpha);
            app.posture.update_position();
            app.posture.draw(app.UIAxes); 
        end

        % Value changed function: RightThighEditField
        function RightThighEditFieldValueChanged(app, event)
            value = app.RightThighEditField.Value;
            if value < app.RightThighSlider.Limits(1)
                app.RightThighSlider.Value = app.RightThighSlider.Limits(1);
            elseif value > app.RightThighSlider.Limits(2)
                app.RightThighSlider.Value = app.RightThighSlider.Limits(2);
            else
                app.RightThighSlider.Value = value;
            end
            
            delta_alpha = value - app.posture.right_thigh.rotation_angle;
            app.posture.right_thigh.rotation(delta_alpha);
            app.posture.update_position();
            app.posture.draw(app.UIAxes); 
        end

        % Value changed function: RightCalfEditField
        function RightCalfEditFieldValueChanged(app, event)
            value = app.RightCalfEditField.Value;
            if value < app.RightCalfSlider.Limits(1)
                app.RightCalfSlider.Value = app.RightCalfSlider.Limits(1);
            elseif value > app.RightCalfSlider.Limits(2)
                app.RightCalfSlider.Value = app.RightCalfSlider.Limits(2);
            else
                app.RightCalfSlider.Value = value;
            end
            
            delta_alpha = value - app.posture.right_calf.rotation_angle;
            app.posture.right_calf.rotation(delta_alpha);
            app.posture.update_position();
            app.posture.draw(app.UIAxes); 
        end

        % Button pushed function: RandomizeButton
        function RandomizeButtonPushed(app, event)
            num_section = length(app.posture.sections);
            for i = 1: num_section
                threshold = app.posture.random_threshold(i, :);
                angle = rand * (threshold(2) - threshold(1)) + threshold(1);
                delta_alpha = angle - app.posture.sections{i}.rotation_angle;
                app.posture.sections{i}.rotation(delta_alpha);
                app.posture.sections{i}.update_link();
            end
            app.posture.position = [0, 0];
            app.posture.update_position();
            app.posture.draw(app.UIAxes); 
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if ~exist('postures','dir')
                mkdir('postures');
            end
            num_postures = size(ls('postures'), 1) - 2;
            num_section = length(app.posture.sections);
            angles = zeros(1, num_section);
            for i = 1: num_section
                angles(i) = app.posture.sections{i}.rotation_angle;
            end
            save(['postures\' num2str(num_postures + 1) '.mat'], "angles");
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            file_path = [app.FileNameEditField.Value];
            if ~exist(file_path, 'file') || exist(file_path, 'dir')
                app.ERRORNosuchfileLabel.Visible = true;
            else 
                app.ERRORNosuchfileLabel.Visible = false;
                load(file_path, "angles");
                num_section = length(app.posture.sections);
                for i = 1: num_section
                    delta_alpha = angles(i) - app.posture.sections{i}.rotation_angle;
                    app.posture.sections{i}.rotation(delta_alpha);
                    app.posture.sections{i}.update_link();
                end
                app.posture.position = [0, 0];
                app.posture.update_position();
                app.posture.draw(app.UIAxes); 
            end 
        end

        % Button pushed function: ChangecolorButton
        function ChangecolorButtonPushed(app, event)
            if mod(app.num_change_color,3)==1
                color=[1, 0, 0];
            elseif mod(app.num_change_color,3)==2
                color=[0, 0, 1];
            else 
                color=[1, 1, 0];
            end
            app.posture.set_color(color .* ones(length(app.posture.sections), 1));
            app.posture.draw(app.UIAxes);
            app.num_change_color=app.num_change_color+1;
        end

        % Button pushed function: clearButton
        function clearButtonPushed(app, event)
            if ~exist('postures','dir')
                mkdir('postures');
            else 
                rmdir('postures', 's');
                mkdir('postures');
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {660, 660, 660};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {660, 660};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {136, '1x', 315};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GenerateButton = uibutton(app.LeftPanel, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.LoadButton = uibutton(app.LeftPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Enable = 'off';
            app.LoadButton.Text = 'Load';
            app.FileNameEditFieldLabel = uilabel(app.LeftPanel);
            app.FileNameEditFieldLabel.Enable = 'off';
            app.FileNameEditFieldLabel.Text = 'FileName';
            app.FileNameEditField = uieditfield(app.LeftPanel, 'text');
            app.FileNameEditField.Enable = 'off';
            app.FileNameEditField.Value = 'postures/';
            app.ERRORNosuchfileLabel = uilabel(app.LeftPanel);
            app.ERRORNosuchfileLabel.Visible = 'off';
            app.ERRORNosuchfileLabel.Text = 'ERROR: No such file';
            app.clearButton = uibutton(app.LeftPanel, 'push');
            app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clearButton.Text = 'clear';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Stage')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XLim = [-10 10];
            app.UIAxes.YLim = [0 16];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.SaveButton = uibutton(app.CenterPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.ChangecolorButton = uibutton(app.CenterPanel, 'push');
            app.ChangecolorButton.ButtonPushedFcn = createCallbackFcn(app, @ChangecolorButtonPushed, true);
            app.ChangecolorButton.Enable = 'off';
            app.ChangecolorButton.Text = 'Change color';
            app.RightPanel = uipanel(app.GridLayout);
            app.HeadSliderLabel = uilabel(app.RightPanel);
            app.HeadSliderLabel.Text = 'Head';
            app.HeadSlider = uislider(app.RightPanel);
            app.HeadSlider.Limits = [-3.14159265358979 3.14159265358979];
            app.HeadSlider.MajorTicks = [-3.14159265358979 3.14159265358979];
            app.HeadSlider.MajorTickLabels = {'-pi', 'pi'};
            app.HeadSlider.ValueChangingFcn = createCallbackFcn(app, @HeadSliderValueChanging, true);
            app.HeadSlider.Enable = 'off';
            app.HeadEditField = uieditfield(app.RightPanel, 'numeric');
            app.HeadEditField.ValueChangedFcn = createCallbackFcn(app, @HeadEditFieldValueChanged, true);
            app.HeadEditField.Enable = 'off';
            app.ChestEditField = uieditfield(app.RightPanel, 'numeric');
            app.ChestEditField.ValueChangedFcn = createCallbackFcn(app, @ChestEditFieldValueChanged, true);
            app.ChestEditField.Enable = 'off';
            app.ChestSliderLabel = uilabel(app.RightPanel);
            app.ChestSliderLabel.Text = 'Chest';
            app.ChestSlider = uislider(app.RightPanel);
            app.ChestSlider.Limits = [-3.14159265358979 3.14159265358979];
            app.ChestSlider.MajorTicks = [-3.14159265358979 3.14159265358979];
            app.ChestSlider.MajorTickLabels = {'-pi', 'pi'};
            app.ChestSlider.ValueChangingFcn = createCallbackFcn(app, @ChestSliderValueChanging, true);
            app.ChestSlider.Enable = 'off';
            app.LeftForearmEditField = uieditfield(app.RightPanel, 'numeric');
            app.LeftForearmEditField.ValueChangedFcn = createCallbackFcn(app, @LeftForearmEditFieldValueChanged, true);
            app.LeftForearmEditField.Enable = 'off';
            app.LeftForearmSliderLabel = uilabel(app.RightPanel);
            app.LeftForearmSliderLabel.Text = 'Left Forearm';
            app.LeftForearmSlider = uislider(app.RightPanel);
            app.LeftForearmSlider.Limits = [-3.14159265358979 3.14159265358979];
            app.LeftForearmSlider.MajorTicks = [-3.14159265358979 3.14159265358979];
            app.LeftForearmSlider.MajorTickLabels = {'-pi', 'pi'};
            app.LeftForearmSlider.ValueChangingFcn = createCallbackFcn(app, @LeftForearmSliderValueChanging, true);
            app.LeftForearmSlider.Enable = 'off';
            app.RightForearmEditField = uieditfield(app.RightPanel, 'numeric');
            app.RightForearmEditField.ValueChangedFcn = createCallbackFcn(app, @RightForearmEditFieldValueChanged, true);
            app.RightForearmEditField.Enable = 'off';
            app.RightForearmSliderLabel = uilabel(app.RightPanel);
            app.RightForearmSliderLabel.Text = 'Right Forearm';
            app.RightForearmSlider = uislider(app.RightPanel);
            app.RightForearmSlider.Limits = [-3.14159265358979 3.14159265358979];
            app.RightForearmSlider.MajorTicks = [-3.14159265358979 3.14159265358979];
            app.RightForearmSlider.MajorTickLabels = {'-pi', 'pi'};
            app.RightForearmSlider.ValueChangingFcn = createCallbackFcn(app, @RightForearmSliderValueChanging, true);
            app.RightForearmSlider.Enable = 'off';
            app.LeftThighEditField = uieditfield(app.RightPanel, 'numeric');
            app.LeftThighEditField.ValueChangedFcn = createCallbackFcn(app, @LeftThighEditFieldValueChanged, true);
            app.LeftThighEditField.Enable = 'off';
            app.LeftThighSliderLabel = uilabel(app.RightPanel);
            app.LeftThighSliderLabel.Text = 'Left Thigh';
            app.LeftThighSlider = uislider(app.RightPanel);
            app.LeftThighSlider.Limits = [-3.14159265358979 3.14159265358979];
            app.LeftThighSlider.MajorTicks = [-3.14159265358979 3.14159265358979];
            app.LeftThighSlider.MajorTickLabels = {'-pi', 'pi'};
            app.LeftThighSlider.ValueChangingFcn = createCallbackFcn(app, @LeftThighSliderValueChanging, true);
            app.LeftThighSlider.Enable = 'off';
            app.LeftCalfEditField = uieditfield(app.RightPanel, 'numeric');
            app.LeftCalfEditField.ValueChangedFcn = createCallbackFcn(app, @LeftCalfEditFieldValueChanged, true);
            app.LeftCalfEditField.Enable = 'off';
            app.LeftCalfSliderLabel = uilabel(app.RightPanel);
            app.LeftCalfSliderLabel.Text = 'Left Calf';
            app.LeftCalfSlider = uislider(app.RightPanel);
            app.LeftCalfSlider.Limits = [-3.14159265358979 3.14159265358979];
            app.LeftCalfSlider.MajorTicks = [-3.14159265358979 3.14159265358979];
            app.LeftCalfSlider.MajorTickLabels = {'-pi', 'pi'};
            app.LeftCalfSlider.ValueChangingFcn = createCallbackFcn(app, @LeftCalfSliderValueChanging, true);
            app.LeftCalfSlider.Enable = 'off';
            app.RightThighEditField = uieditfield(app.RightPanel, 'numeric');
            app.RightThighEditField.ValueChangedFcn = createCallbackFcn(app, @RightThighEditFieldValueChanged, true);
            app.RightThighEditField.Enable = 'off';
            app.RightThighSliderLabel = uilabel(app.RightPanel);
            app.RightThighSliderLabel.Text = 'Right Thigh';
            app.RightThighSlider = uislider(app.RightPanel);
            app.RightThighSlider.Limits = [-3.14159265358979 3.14159265358979];
            app.RightThighSlider.MajorTicks = [-3.14159265358979 3.14159265358979];
            app.RightThighSlider.MajorTickLabels = {'-pi', 'pi'};
            app.RightThighSlider.ValueChangingFcn = createCallbackFcn(app, @RightThighSliderValueChanging, true);
            app.RightThighSlider.Enable = 'off';
            app.RightCalfEditField = uieditfield(app.RightPanel, 'numeric');
            app.RightCalfEditField.ValueChangedFcn = createCallbackFcn(app, @RightCalfEditFieldValueChanged, true);
            app.RightCalfEditField.Enable = 'off';
            app.RightCalfSliderLabel = uilabel(app.RightPanel);
            app.RightCalfSliderLabel.Text = 'Right Calf';
            app.RightCalfSlider = uislider(app.RightPanel);
            app.RightCalfSlider.Limits = [-3.14159265358979 3.14159265358979];
            app.RightCalfSlider.MajorTicks = [-3.14159265358979 3.14159265358979];
            app.RightCalfSlider.MajorTickLabels = {'-pi', 'pi'};
            app.RightCalfSlider.ValueChangingFcn = createCallbackFcn(app, @RightCalfSliderValueChanging, true);
            app.RightCalfSlider.Enable = 'off';
            app.WaistSliderLabel = uilabel(app.RightPanel);
            app.WaistSliderLabel.Text = 'Waist';
            app.WaistSlider = uislider(app.RightPanel);
            app.WaistSlider.Limits = [-3.14159265358979 3.14159265358979];
            app.WaistSlider.MajorTicks = [-3.14159265358979 3.14159265358979];
            app.WaistSlider.MajorTickLabels = {'-pi', 'pi'};
            app.WaistSlider.ValueChangingFcn = createCallbackFcn(app, @WaistSliderValueChanging, true);
            app.WaistSlider.Enable = 'off';
            app.WaistEditField = uieditfield(app.RightPanel, 'numeric');
            app.WaistEditField.ValueChangedFcn = createCallbackFcn(app, @WaistEditFieldValueChanged, true);
            app.WaistEditField.Enable = 'off';
            app.LeftArmSliderLabel = uilabel(app.RightPanel);
            app.LeftArmSliderLabel.Text = 'Left Arm';
            app.LeftArmSlider = uislider(app.RightPanel);
            app.LeftArmSlider.Limits = [-3.14159265358979 3.14159265358979];
            app.LeftArmSlider.MajorTicks = [-3.14159265358979 3.14159265358979];
            app.LeftArmSlider.MajorTickLabels = {'-pi', 'pi'};
            app.LeftArmSlider.ValueChangingFcn = createCallbackFcn(app, @LeftArmSliderValueChanging, true);
            app.LeftArmSlider.Enable = 'off';
            app.LeftArmEditField = uieditfield(app.RightPanel, 'numeric');
            app.LeftArmEditField.ValueChangedFcn = createCallbackFcn(app, @LeftArmEditFieldValueChanged, true);
            app.LeftArmEditField.Enable = 'off';
            app.RightArmSliderLabel = uilabel(app.RightPanel);
            app.RightArmSliderLabel.Text = 'Right Arm';
            app.RightArmSlider = uislider(app.RightPanel);
            app.RightArmSlider.Limits = [-3.14159265358979 3.14159265358979];
            app.RightArmSlider.MajorTicks = [-3.14159265358979 3.14159265358979];
            app.RightArmSlider.MajorTickLabels = {'-pi', 'pi'};
            app.RightArmSlider.ValueChangingFcn = createCallbackFcn(app, @RightArmSliderValueChanging, true);
            app.RightArmSlider.Enable = 'off';
            app.RightArmEditField = uieditfield(app.RightPanel, 'numeric');
            app.RightArmEditField.ValueChangedFcn = createCallbackFcn(app, @RightArmEditFieldValueChanged, true);
            app.RightArmEditField.Enable = 'off';
            app.RandomizeButton = uibutton(app.RightPanel, 'push');
            app.RandomizeButton.ButtonPushedFcn = createCallbackFcn(app, @RandomizeButtonPushed, true);
            app.RandomizeButton.Enable = 'off';
            app.RandomizeButton.Text = 'Randomize';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = posture_editer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end