classdef app_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        LeftPanel          matlab.ui.container.Panel
        RandomDanceButton  matlab.ui.control.Button
        GenerateButton     matlab.ui.control.Button
        HeadSliderLabel    matlab.ui.control.Label
        HeadSlider         matlab.ui.control.Slider
        HintTextAreaLabel  matlab.ui.control.Label
        HintTextArea       matlab.ui.control.TextArea
        StopButton         matlab.ui.control.Button
        StartButton        matlab.ui.control.Button
        RightPanel         matlab.ui.container.Panel
        UIAxes             matlab.ui.control.UIAxes
        GaugeLabel         matlab.ui.control.Label
        Gauge              matlab.ui.control.LinearGauge
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        current_robot % the robot displayed
        current_robot_exist = false
        break_flag = false % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RandomDanceButton
        function RandomDanceButtonPushed(app, event)
            random_dance(app.UIAxes);
        end

        % Callback function
        function test(app, event)
            gca
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            app.current_robot = robot('R');
            app.current_robot.draw(app.UIAxes);
            app.current_robot_exist = true;
        end

        % Value changing function: HeadSlider
        function HeadSliderValueChanging(app, event)
            if ~app.current_robot_exist
                app.HintTextArea.Visible = true;
                app.HintTextArea.Value = 'Push GENERATE to generate a robot';
            else 
                app.HintTextArea.Visible = false;
                changingValue = event.Value;
                disp(changingValue);
                delta_alpha = changingValue - app.current_robot.head.rotation_angle;
                app.current_robot.head.rotation(delta_alpha);
                app.current_robot = app.current_robot.update_position();
                app.current_robot.draw(app.UIAxes);
            end
        end

        % Value changed function: HeadSlider
        function HeadSliderValueChanged(app, event)
            value = app.HeadSlider.Value;
            if ~app.current_robot_exist
                app.HeadSlider.Value = 0;
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            pause(0);
            app.break_flag = true;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            while app.Gauge.Value < app.Gauge.Limits(2)
                app.Gauge.Value = app.Gauge.Value + 1;
                if app.break_flag
%                     break;
                end
                 pause(0.01);
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {564, 564};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {262, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RandomDanceButton = uibutton(app.LeftPanel, 'push');
            app.RandomDanceButton.ButtonPushedFcn = createCallbackFcn(app, @RandomDanceButtonPushed, true);
            app.RandomDanceButton.Text = 'Random Dance';
            app.GenerateButton = uibutton(app.LeftPanel, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.HeadSliderLabel = uilabel(app.LeftPanel);
            app.HeadSliderLabel.Text = 'Head';
            app.HeadSlider = uislider(app.LeftPanel);
            app.HeadSlider.Limits = [-3.14159265358979 3.14159265358979];
            app.HeadSlider.ValueChangedFcn = createCallbackFcn(app, @HeadSliderValueChanged, true);
            app.HeadSlider.ValueChangingFcn = createCallbackFcn(app, @HeadSliderValueChanging, true);
            app.HintTextAreaLabel = uilabel(app.LeftPanel);
            app.HintTextAreaLabel.Text = 'Hint';
            app.HintTextArea = uitextarea(app.LeftPanel);
            app.HintTextArea.Editable = 'off';
            app.HintTextArea.Visible = 'off';
            app.StopButton = uibutton(app.LeftPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Stage')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [-10 10];
            app.UIAxes.YLim = [0 17];
            app.GaugeLabel = uilabel(app.RightPanel);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.RightPanel, 'linear');
            app.Gauge.Limits = [0 1000];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end